classdef covid_data < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        COVID19DataVisualizationDataSourceUIFigure  matlab.ui.Figure
        OptionButtonGroup            matlab.ui.container.ButtonGroup
        DailyButton                  matlab.ui.control.RadioButton
        CumulativeButton             matlab.ui.control.RadioButton
        DatatoPlotButtonGroup        matlab.ui.container.ButtonGroup
        BothButton                   matlab.ui.control.RadioButton
        DeathsButton                 matlab.ui.control.RadioButton
        CasesButton                  matlab.ui.control.RadioButton
        AveragedofdaysSlider         matlab.ui.control.Slider
        AveragedofdaysSliderLabel    matlab.ui.control.Label
        StateorProvinceListBox       matlab.ui.control.ListBox
        StateorProvinceListBoxLabel  matlab.ui.control.Label
        CountryListBox               matlab.ui.control.ListBox
        CountryListBoxLabel          matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CovidData
        Country
        State
        States
        Rows
        Cols
        CCases
        CDeaths
        Cases
        Deaths
        XTicks
        Xidx
        AvgDays   
    end
    
    methods (Access = private)
        function StatesDisp(app) % Displays appropriate states
            app.Country = app.CountryListBox.Value;
            app.States= {'All'};
            if strcmp(app.Country,'Global')
                app.StateorProvinceListBox.Items = app.States; return
            end
            for i = 1:app.Rows
                if strcmp(app.Country, app.CovidData{i,1}) && ~isempty(app.CovidData{i,2})
                    app.States = [app.States; app.CovidData(i,2)];
                end
            end
            app.StateorProvinceListBox.Items = app.States;
        end
        
        function Infor(app) % Captures raw info for particular Country+State
            app.State = app.StateorProvinceListBox.Value;
            if strcmp(app.State, 'All')
                app.State = '';
            end
            
            app.CCases = zeros([1,app.Cols-2]); app.CDeaths = app.CCases;
            for i = 1:app.Rows
                if  strcmp(app.Country,'Global') && isempty(app.CovidData{i,2})
                    v = app.CovidData(i,3:end);
                    cases = zeros([1,app.Cols-2]); deaths = cases;
                    for j = 1:app.Cols-2
                        cases(1,j) = v{j}(1); deaths(1,j) = v{j}(2);
                    end
                    app.CCases = app.CCases + cases; app.CDeaths = app.CDeaths + deaths;
                
                elseif strcmp(app.Country, app.CovidData{i,1}) && strcmp(app.State, app.CovidData{i,2})
                    v = app.CovidData(i,3:end);
                    for j = 1:app.Cols-2
                        app.CCases(1,j) = v{j}(1); app.CDeaths(1,j) = v{j}(2);
                    end
                end
            end
        end
        
        function Data(app) % Formats raw info into Cumu/Daily data acc to slider value
            if app.DailyButton.Value
                cases = [app.CCases(1), max(0,diff(app.CCases))];
                app.Cases = movmean(cases,[app.AvgDays,0]);
                deaths = [app.CDeaths(1), max(0,diff(app.CDeaths))];
                app.Deaths = movmean(deaths,[app.AvgDays,0]);
            elseif app.CumulativeButton.Value
                cases = app.CCases; deaths = app.CDeaths;
                app.Cases = movmean(cases,[app.AvgDays,0]);
                app.Deaths = movmean(deaths,[app.AvgDays,0]);
            end
        end
               
        function Plot(app) % Plot based on Cases/Deaths selection
            if app.CasesButton.Value
                cla(app.UIAxes, 'reset')
                bar(app.UIAxes,1:app.Cols-2,app.Cases)
            elseif app.DeathsButton.Value
                cla(app.UIAxes, 'reset')
                plot(app.UIAxes,1:app.Cols-2,app.Deaths,'r','LineWidth',1);
            elseif app.BothButton.Value
                cla(app.UIAxes, 'reset')
                bar(app.UIAxes,1:app.Cols-2,app.Cases)
                yyaxis(app.UIAxes, 'right')
                plot(app.UIAxes,1:app.Cols-2,app.Deaths,'r','LineWidth',1);
            end
            xlim(app.UIAxes, [1, app.Cols-2])
            xticks(app.UIAxes, app.Xidx)
            xticklabels(app.UIAxes, app.XTicks(app.Xidx))
            title(app.UIAxes, app.Title())
            grid(app.UIAxes, 'on')
        end
        
        function title = Title(app)
            cd = 'Cumulative Number of ';
            if app.DailyButton.Value
                cd = 'Daily Number of ';
            end
            
            dc = 'Cases';
            if app.DeathsButton.Value
                dc = 'Deaths';
            elseif app.BothButton.Value
                dc = 'Cases and Deaths';
            end
            
            if app.AvgDays>1
                dm = [' (',num2str(app.AvgDays),'-day mean)'];
            else
                dm = '';
            end
            
            loc = app.Country;
            if ~isempty(app.State)
                loc = [app.State,', ',app.Country];
            end
            
            title = [cd, ' Number of ', dc, ' in ', loc, dm];
            if strcmp(app.Country, 'Global')
                title = [cd, 'Number of ', dc, ' Globally', dm];
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            load covid_data.mat covid_data;
            app.CovidData = covid_data(2:end,:);
            app.XTicks = covid_data(1,3:end);
            app.Xidx = [];
            for i = 1:length(app.XTicks)
                app.XTicks{i} = app.XTicks{i}(1:end-3);
                if strcmp(app.XTicks{i}(end-1:end), '/1')
                    app.Xidx = [app.Xidx, i];
                end
            end
            
            [app.Rows, app.Cols] = size(app.CovidData);
            app.CountryListBox.Items = [{'Global'}; unique(app.CovidData(:,1))];
            app.AvgDays = app.AveragedofdaysSlider.Limits(1);
            app.CountryListBoxValueChanged();                                 
        end

        % Value changed function: CountryListBox
        function CountryListBoxValueChanged(app, event)
            app.StatesDisp();
            app.Infor();
            app.Data();
            app.Plot();
        end

        % Value changed function: StateorProvinceListBox
        function StateorProvinceListBoxValueChanged(app, event)
            app.Infor();
            app.Data();
            app.Plot();
        end

        % Selection changed function: DatatoPlotButtonGroup
        function DatatoPlotButtonGroupSelectionChanged(app, event)
            app.Plot();
        end

        % Selection changed function: OptionButtonGroup
        function OptionButtonGroupSelectionChanged(app, event)
            app.Data();
            app.Plot();
        end

        % Value changing function: AveragedofdaysSlider
        function AveragedofdaysSliderValueChanging(app, event)
            a = event.Value;
            app.AvgDays = round(a);
            app.Data();
            app.Plot();
        end

        % Value changed function: AveragedofdaysSlider
        function AveragedofdaysSliderValueChanged(app, event)
            app.AveragedofdaysSlider.Value = app.AvgDays;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.COVID19DataVisualizationDataSourceUIFigure = uifigure('Visible', 'off');
            app.COVID19DataVisualizationDataSourceUIFigure.Name = 'COVID-19 Data Visualization (Data Source: Johns Hopkins University)';
            app.UIAxes = uiaxes(app.COVID19DataVisualizationDataSourceUIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.CountryListBoxLabel = uilabel(app.COVID19DataVisualizationDataSourceUIFigure);
            app.CountryListBoxLabel.Text = 'Country:';
            app.CountryListBox = uilistbox(app.COVID19DataVisualizationDataSourceUIFigure);
            app.CountryListBox.ValueChangedFcn = createCallbackFcn(app, @CountryListBoxValueChanged, true);
            app.StateorProvinceListBoxLabel = uilabel(app.COVID19DataVisualizationDataSourceUIFigure);
            app.StateorProvinceListBoxLabel.Text = {'State or'; 'Province:'};
            app.StateorProvinceListBox = uilistbox(app.COVID19DataVisualizationDataSourceUIFigure);
            app.StateorProvinceListBox.ValueChangedFcn = createCallbackFcn(app, @StateorProvinceListBoxValueChanged, true);
            app.AveragedofdaysSliderLabel = uilabel(app.COVID19DataVisualizationDataSourceUIFigure);
            app.AveragedofdaysSliderLabel.Text = {'Averaged'; '# of days:'};
            app.AveragedofdaysSlider = uislider(app.COVID19DataVisualizationDataSourceUIFigure);
            app.AveragedofdaysSlider.Limits = [1 15];
            app.AveragedofdaysSlider.MajorTicks = [1 3 5 7 9 11 13 15];
            app.AveragedofdaysSlider.MajorTickLabels = {'1', '3', '5', '7', '9', '11', '13', '15'};
            app.AveragedofdaysSlider.ValueChangedFcn = createCallbackFcn(app, @AveragedofdaysSliderValueChanged, true);
            app.AveragedofdaysSlider.ValueChangingFcn = createCallbackFcn(app, @AveragedofdaysSliderValueChanging, true);
            app.AveragedofdaysSlider.MinorTicks = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15];
            app.AveragedofdaysSlider.Value = 1;
            app.DatatoPlotButtonGroup = uibuttongroup(app.COVID19DataVisualizationDataSourceUIFigure);
            app.DatatoPlotButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DatatoPlotButtonGroupSelectionChanged, true);
            app.DatatoPlotButtonGroup.Title = 'Data to Plot';
            app.CasesButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.CasesButton.Text = 'Cases';
            app.CasesButton.Value = true;
            app.DeathsButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.DeathsButton.Text = 'Deaths';
            app.BothButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.BothButton.Text = 'Both';
            app.OptionButtonGroup = uibuttongroup(app.COVID19DataVisualizationDataSourceUIFigure);
            app.OptionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @OptionButtonGroupSelectionChanged, true);
            app.OptionButtonGroup.Title = 'Option';
            app.CumulativeButton = uiradiobutton(app.OptionButtonGroup);
            app.CumulativeButton.Text = 'Cumulative';
            app.CumulativeButton.Value = true;
            app.DailyButton = uiradiobutton(app.OptionButtonGroup);
            app.DailyButton.Text = 'Daily';
            % Show the figure after all components are created
            app.COVID19DataVisualizationDataSourceUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = covid_data

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.COVID19DataVisualizationDataSourceUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.COVID19DataVisualizationDataSourceUIFigure)
        end
    end
end